classdef basicApp_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        spectrumAxis                  matlab.ui.control.UIAxes
        ConnectSpectrometerButton     matlab.ui.control.Button
        spectrometerInfoTable         matlab.ui.control.Table
        IntegrationTimeusKnobLabel    matlab.ui.control.Label
        IntegrationTimeusKnob         matlab.ui.control.Knob
        SpectrometerSettingsLabel     matlab.ui.control.Label
        DisconnectSpectrometerButton  matlab.ui.control.Button
        SnapSpectrumButton            matlab.ui.control.Button
        GrabSpectrumButton            matlab.ui.control.StateButton
        YScaleSwitchLabel             matlab.ui.control.Label
        YScaleSwitch                  matlab.ui.control.ToggleSwitch
        YMinEditFieldLabel            matlab.ui.control.Label
        YMinEditField                 matlab.ui.control.NumericEditField
        YMaxEditFieldLabel            matlab.ui.control.Label
        YMaxEditField                 matlab.ui.control.NumericEditField
        XScaleSwitchLabel             matlab.ui.control.Label
        XScaleSwitch                  matlab.ui.control.ToggleSwitch
        XMinEditFieldLabel            matlab.ui.control.Label
        XMinEditField                 matlab.ui.control.NumericEditField
        XMaxEditFieldLabel            matlab.ui.control.Label
        XMaxEditField                 matlab.ui.control.NumericEditField
        FrameRateGaugeLabel           matlab.ui.control.Label
        FrameRateGauge                matlab.ui.control.SemicircularGauge
        ConnectedLampLabel            matlab.ui.control.Label
        ConnectedLamp                 matlab.ui.control.Lamp
        FTAxis                        matlab.ui.control.UIAxes
        OnlineAnalysisSwitchLabel     matlab.ui.control.Label
        OnlineAnalysisSwitch          matlab.ui.control.Switch
    end


    properties (Access = public)
        spectrometerObj     % spectrometer Object
        spectrometerIndex
        channelIndex
        wavelengths
        spectralData
    end


    methods (Access = private)

        % Button pushed function: ConnectSpectrometerButton
        function ConnectSpectrometerButtonPushed(app, event)
            addpath('..\InstrumentDrivers\OceanOptics_OmniDriver\');
            addpath('..\');
            % Create MATLAB Instrument OmniDriver object.
            app.spectrometerObj = icdevice('OceanOptics_OmniDriver.mdd');
            
            % Connect to the instrument.
            connect(app.spectrometerObj);
            disp(app.spectrometerObj);
            app.ConnectedLamp.Color = [0,1,0];
            
            % Get number of spectrometers connected.
            numOfSpectrometers = invoke(app.spectrometerObj, 'getNumberOfSpectrometersFound');
            
            % Use the first spectrometer by default
            app.spectrometerIndex = 0;
            app.channelIndex = 0;
            % Get spectrometer name. 
            spectrometerName = invoke(app.spectrometerObj, 'getName', app.spectrometerIndex);
            % Get spectrometer serial number.
            spectrometerSerialNumber = invoke(app.spectrometerObj, 'getSerialNumber', app.spectrometerIndex);

            % Get the current integration time
            integrationTime = invoke(app.spectrometerObj,'getIntegrationTime',app.spectrometerIndex,app.channelIndex);
            app.IntegrationTimeusKnob.Value = integrationTime;
            
            
             % Put all this crap in a table            
            Params = {'Model Name';'Model S/N';'Spectrometer Index';'Channel Index';'Integration Time (us)'}
            Values = {spectrometerName;spectrometerSerialNumber;app.spectrometerIndex;app.channelIndex;integrationTime};
            app.spectrometerInfoTable.Data = table(Params,Values);
        end

        % Value changed function: IntegrationTimeusKnob
        function IntegrationTimeusKnobValueChanged(app, event)
            integrationTime = app.IntegrationTimeusKnob.Value;
            
%             % Get the spectrometer Index and channel index
            T = app.spectrometerInfoTable.Data;
%             out=T(ismember(T.Params,'Spectrometer Index'),:)
%             spectrometerIndex = cell2mat(out.Values);
%             out=T(ismember(T.Params,'Channel Index'),:)
%             channelIndex = cell2mat(out.Values);
            
            invoke(app.spectrometerObj, 'setIntegrationTime', app.spectrometerIndex, app.channelIndex, integrationTime);
            
            % Now change the value in the settings
            indx = find(strcmp(T.Params,'Integration Time (us)'));
            T.Values{indx} = integrationTime;
            app.spectrometerInfoTable.Data = T;
        end

        % Button pushed function: DisconnectSpectrometerButton
        function DisconnectSpectrometerButtonPushed(app, event)
            disconnect(app.spectrometerObj);
            delete (app.spectrometerObj);
            app.ConnectedLamp.Color = [.8,1,.8];
        end

        % Button pushed function: SnapSpectrumButton
        function SnapSpectrumButtonPushed(app, event)
            ax = app.spectrumAxis;
            % Aquire the spectrum.
            app.wavelengths = invoke(app.spectrometerObj, 'getWavelengths', app.spectrometerIndex, app.channelIndex);
            % Get the wavelengths of the first spectrometer and save them in a double array.
            app.spectralData = invoke(app.spectrometerObj, 'getSpectrum', app.spectrometerIndex);
            grid(ax,'minor')
           plot(ax,app.wavelengths,app.spectralData,'Color',[.5 .5 1])
           %grid(ax,'on')
           
           if strcmp(app.YScaleSwitch.Value,'Auto')
                ylim(ax,[0.75*min(app.spectralData),1.1*max(app.spectralData)])
           else
                ylim(ax,[app.YMinEditField.Value,app.YMaxEditField.Value])
           end  
           if strcmp(app.XScaleSwitch.Value,'Auto')
                xlim(ax,[min(app.wavelengths),max(app.wavelengths)])
           else
                xlim(ax,[app.XMinEditField.Value,app.XMaxEditField.Value])
           end           
        end

        % Value changed function: GrabSpectrumButton
        function GrabSpectrumButtonValueChanged(app, event)
            value = app.GrabSpectrumButton.Value;
            
            ax = app.spectrumAxis;
            ax2 = app.FTAxis;
            grid(ax,'minor')
            while true
                tic;
                % Check if spectrum is to be aquired
                value = app.GrabSpectrumButton.Value;
                if value == false
                    break;
                end
                
                % Aquire the spectrum.
                app.wavelengths = invoke(app.spectrometerObj, 'getWavelengths', app.spectrometerIndex, app.channelIndex);
                % Get the wavelengths of the first spectrometer and save them in a double array.
                app.spectralData = invoke(app.spectrometerObj, 'getSpectrum', app.spectrometerIndex);
            
                plot(ax,app.wavelengths,app.spectralData,'Color',[.5 .5 1])
                %grid(ax,'on')
                %grid(ax,'minor')
                if strcmp(app.YScaleSwitch.Value,'Auto')
                    ylim(ax,[0.75*min(app.spectralData),1.1*max(app.spectralData)])
                else
                    ylim(ax,[app.YMinEditField.Value,app.YMaxEditField.Value])
                end
                if strcmp(app.XScaleSwitch.Value,'Auto')
                    xlim(ax,[min(app.wavelengths),max(app.wavelengths)])
                else
                    xlim(ax,[app.XMinEditField.Value,app.XMaxEditField.Value])
                end
                
                if strcmp(app.OnlineAnalysisSwitch.Value,'On')
                    
                    [Delta_t, t_error, t, IFT] = analyseSpectrum(app.wavelengths*1e-9,app.spectralData,'fft',0);
                    plot(ax2,t*1e12,log10(abs(IFT)),'Color',[.5 .5 1]);
              
                end
                
                drawnow()
                elapsedTime = toc;
                app.FrameRateGauge.Value = 1/elapsedTime;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.spectrumAxis = uiaxes(app.UIFigure);
            title(app.spectrumAxis, 'Spectrum')
            xlabel(app.spectrumAxis, 'Wavelength (nm)')
            ylabel(app.spectrumAxis, 'Counts')
            app.ConnectSpectrometerButton = uibutton(app.UIFigure, 'push');
            app.ConnectSpectrometerButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectSpectrometerButtonPushed, true);
            app.ConnectSpectrometerButton.Text = 'Connect Spectrometer';
            app.spectrometerInfoTable = uitable(app.UIFigure);
            app.spectrometerInfoTable.ColumnName = {'Parameter'; 'Value'};
            app.spectrometerInfoTable.RowName = {};
            app.IntegrationTimeusKnobLabel = uilabel(app.UIFigure);
            app.IntegrationTimeusKnobLabel.Text = 'Integration Time (us)';
            app.IntegrationTimeusKnob = uiknob(app.UIFigure, 'continuous');
            app.IntegrationTimeusKnob.Limits = [10 4000];
            app.IntegrationTimeusKnob.ValueChangedFcn = createCallbackFcn(app, @IntegrationTimeusKnobValueChanged, true);
            app.IntegrationTimeusKnob.Value = 10;
            app.SpectrometerSettingsLabel = uilabel(app.UIFigure);
            app.SpectrometerSettingsLabel.Text = 'Spectrometer Settings';
            app.DisconnectSpectrometerButton = uibutton(app.UIFigure, 'push');
            app.DisconnectSpectrometerButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectSpectrometerButtonPushed, true);
            app.DisconnectSpectrometerButton.Text = 'Disconnect Spectrometer';
            app.SnapSpectrumButton = uibutton(app.UIFigure, 'push');
            app.SnapSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @SnapSpectrumButtonPushed, true);
            app.SnapSpectrumButton.Text = 'Snap Spectrum';
            app.GrabSpectrumButton = uibutton(app.UIFigure, 'state');
            app.GrabSpectrumButton.ValueChangedFcn = createCallbackFcn(app, @GrabSpectrumButtonValueChanged, true);
            app.GrabSpectrumButton.Text = 'Grab Spectrum';
            app.YScaleSwitchLabel = uilabel(app.UIFigure);
            app.YScaleSwitchLabel.Text = 'Y Scale';
            app.YScaleSwitch = uiswitch(app.UIFigure, 'toggle');
            app.YScaleSwitch.Items = {'Fixed', 'Auto'};
            app.YScaleSwitch.Value = 'Fixed';
            app.YMinEditFieldLabel = uilabel(app.UIFigure);
            app.YMinEditFieldLabel.Text = 'YMin';
            app.YMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.YMaxEditFieldLabel = uilabel(app.UIFigure);
            app.YMaxEditFieldLabel.Text = 'YMax';
            app.YMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.YMaxEditField.Value = 16000;
            app.XScaleSwitchLabel = uilabel(app.UIFigure);
            app.XScaleSwitchLabel.Text = 'X Scale';
            app.XScaleSwitch = uiswitch(app.UIFigure, 'toggle');
            app.XScaleSwitch.Items = {'Fixed', 'Auto'};
            app.XScaleSwitch.Value = 'Fixed';
            app.XMinEditFieldLabel = uilabel(app.UIFigure);
            app.XMinEditFieldLabel.Text = 'XMin';
            app.XMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.XMinEditField.Value = 200;
            app.XMaxEditFieldLabel = uilabel(app.UIFigure);
            app.XMaxEditFieldLabel.Text = 'XMax';
            app.XMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.XMaxEditField.Value = 1200;
            app.FrameRateGaugeLabel = uilabel(app.UIFigure);
            app.FrameRateGaugeLabel.Text = 'Frame Rate';
            app.FrameRateGauge = uigauge(app.UIFigure, 'semicircular');
            app.FrameRateGauge.Limits = [0 50];
            app.ConnectedLampLabel = uilabel(app.UIFigure);
            app.ConnectedLampLabel.Text = 'Connected';
            app.ConnectedLamp = uilamp(app.UIFigure);
            app.FTAxis = uiaxes(app.UIFigure);
            title(app.FTAxis, 'Fourier Transform of Spectrum')
            xlabel(app.FTAxis, 'Time (ps)')
            ylabel(app.FTAxis, 'Counts (arb.)')
            app.OnlineAnalysisSwitchLabel = uilabel(app.UIFigure);
            app.OnlineAnalysisSwitchLabel.Text = 'Online Analysis';
            app.OnlineAnalysisSwitch = uiswitch(app.UIFigure, 'slider');
        end
    end

    methods (Access = public)

        % Construct app
        function app = basicApp_v1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end